classdef Orbitals_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        EnterButton              matlab.ui.control.Button
        TLEFormatTextArea        matlab.ui.control.TextArea
        TLEFormatTextAreaLabel   matlab.ui.control.Label
        TrackingTypeButtonGroup  matlab.ui.container.ButtonGroup
        SpaceTrackButton         matlab.ui.control.ToggleButton
        GroundTrackButton        matlab.ui.control.ToggleButton
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Satalite_TLE
        Satalite
        mu = 398600;
        R_E = 6378;
    end
    
    methods (Access = private)
        function Create_Orbit(app)
            
        end
        function Parse_TLE(app)
            app.Satalite.Name = strtrim(app.Satalite_Satalite_Name);
            app.Satalite.Inclination = deg2rad(double(app.Satalite_TLE.Inclination));
            app.Satalite.RAAN = deg2rad(double(app.Satalite_TLE.RAAN));
            app.Satalite.Eccentricity = double(app.Satalite_TLE.Eccentricity) * 1e-7;
            app.Satalite.Argument_Of_Perigee = deg2rad(double(app.Satalite_TLE.Argument_Of_Perigee));
            app.Satalite.Mean_Anomaly = deg2rad(double(app.Satalite_TLE.Mean_Anomaly));
            app.Satalite.Mean_Motion = double(app.Satalite_TLE.Mean_Motion);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TrackingTypeButtonGroupSelectionChanged(app.GroundTrackButton);
        end

        % Selection changed function: TrackingTypeButtonGroup
        function TrackingTypeButtonGroupSelectionChanged(app, event)
            Tracking_Type = app.TrackingTypeButtonGroup.SelectedObject;
            switch Tracking_Type
                case app.GroundTrackButton
                    load('coastlines.mat','coastlon','coastlat');
                    plot(app.UIAxes, coastlon, coastlat, 'b');
                    grid(app.UIAxes, 'on');
                    xlim(app.UIAxes, [-180, 180]);
                    ylim(app.UIAxes,[-90 90]);
                    app.UIAxes.XTick = -180:30:180;
                    app.UIAxes.YTick = -90:15:90;
                    app.UIAxes.GridLineStyle = '-.';
                case app.SpaceTrackButton
            end
        end

        % Value changed function: TLEFormatTextArea
        function TLEFormatTextAreaValueChanged(app, event)
            TLE_Text = app.TLEFormatTextArea.Value;
            TLE_Line_0 = TLE_Text{1};
            TLE_Line_1 = TLE_Text{2};
            TLE_Line_2 = TLE_Text{3};
            app.Satalite_TLE.Satalite_Name = TLE_Line_0;

            app.Satalite_TLE.Satellite_Number_1 = TLE_Line_1(3:7);
            app.Satalite_TLE.Classification = TLE_Line_1(8);
            app.Satalite_TLE.International_Designator = TLE_Line_1(10:17);
            app.Satalite_TLE.Epoch_Year = TLE_Line_1(19:20);
            app.Satalite_TLE.Epoch_Day = TLE_Line_1(21:32);
            app.Satalite_TLE.Mean_Motion_Dot = TLE_Line_1(34:43);
            app.Satalite_TLE.Mean_Motion_Dot_Dot = TLE_Line_1(45:52);
            app.Satalite_TLE.BSTAR_Drag_Term = TLE_Line_1(54:61);
            app.Satalite_TLE.Ephemeris_Type = TLE_Line_1(63);
            app.Satalite_TLE.Element_Set_Number = TLE_Line_1(65:68);
            app.Satalite_TLE.Checksum_1 = TLE_Line_1(69);

            app.Satalite_TLE.Satellite_Number_2 = TLE_Line_2(3:7);
            app.Satalite_TLE.Inclination = TLE_Line_2(9:16);
            app.Satalite_TLE.RAAN = TLE_Line_2(18:25);
            app.Satalite_TLE.Eccentricity = TLE_Line_2(27:33);
            app.Satalite_TLE.Argument_Of_Perigee = TLE_Line_2(35:42);
            app.Satalite_TLE.Mean_Anomaly = TLE_Line_2(44:51);
            app.Satalite_TLE.Mean_Motion = TLE_Line_2(53:63);
            app.Satalite_TLE.Revolution_Number = TLE_Line_2(64:68);
            app.Satalite_TLE.Checksum_2 = TLE_Line_2(69);
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TrackingTypeButtonGroup = uibuttongroup(app.GridLayout);
            app.TrackingTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrackingTypeButtonGroupSelectionChanged, true);
            app.TrackingTypeButtonGroup.Title = 'Tracking Type';
            app.GroundTrackButton = uitogglebutton(app.TrackingTypeButtonGroup);
            app.GroundTrackButton.Text = 'Ground Track';
            app.GroundTrackButton.Value = true;
            app.SpaceTrackButton = uitogglebutton(app.TrackingTypeButtonGroup);
            app.SpaceTrackButton.Text = 'Space Track';
            app.TLEFormatTextAreaLabel = uilabel(app.GridLayout);
            app.TLEFormatTextAreaLabel.Text = 'TLE Format:';
            app.TLEFormatTextArea = uitextarea(app.GridLayout);
            app.TLEFormatTextArea.ValueChangedFcn = createCallbackFcn(app, @TLEFormatTextAreaValueChanged, true);
            app.EnterButton = uibutton(app.GridLayout, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Orbitals_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end